classdef TicTacToe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Button_3                matlab.ui.control.Button
        TextArea_2Label         matlab.ui.control.Label
        TextArea_Win            matlab.ui.control.TextArea
        PlaySinglePlayerButton  matlab.ui.control.Button
        TextArea_PlayerTurn     matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        Button_9                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_1                matlab.ui.control.Button
    end

    properties (Access = public)
        Image       matlab.ui.control.Image
        turn=1; % count number of turns(max==10)
        x=[0 0 0;0 0 0;0 0 0]; % game board initailize (0->empty, 1>>X's move, -1>>O's move)
        singleplayer=false;
        gameover=false;
    end
    methods (Access = public)
        
        
        %AI of singleplayer mode 
        function AI(app)
            done=false;
            for i=1:3
                if sum(app.x(:,i))==-2
                    done=true;
                    temp=[];
                    temp=app.x(:,i);
                    app.x(find(temp==0),i)=-1; 
                    fn = sprintf('Button_%d',i+3*(find(temp==0)-1));
                    app.(fn).Enable='off';
                    app.turn=app.turn+1;
                    app.displayimage;
                    app.Image.Layout.Row = find(temp==0);
                    app.Image.Layout.Column = i;
                    app.Image.ImageSource = 'Circle.jpg';
                    app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                    app.GameLogic(0)
                    break
                end
            end
            for i=1:3
                if sum(app.x(i,:))==-2 && ~done
                    done=true;
                    temp=[];
                    temp=app.x(i,:);
                    app.x(i,find(temp==0))=-1;
                    fn = sprintf('Button_%d',find(temp==0)+3*(i-1));
                    app.(fn).Enable='off';
                    app.turn=app.turn+1;
                    app.displayimage;
                    app.Image.Layout.Row = i;
                    app.Image.Layout.Column = find(temp==0);
                    app.Image.ImageSource = 'Circle.jpg';
                    app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                    app.GameLogic(0)
                    break
                end
            end
            if sum(diag(app.x))==-2 && ~done
                done=true;
                temp=[];
                temp=find(diag(app.x)==0);
                app.x(temp,temp)=-1;
                fn = sprintf('Button_%d',temp^2+1-rem(temp,2));
                app.(fn).Enable='off';
                app.turn=app.turn+1;
                app.displayimage;
                app.Image.Layout.Row = temp;
                app.Image.Layout.Column = temp;
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                app.GameLogic(0)
            end
            if sum([app.x(3,1) app.x(2,2) app.x(1,3)])==-2 && ~done
                done=true;
                temp=[];
                temp=find([app.x(3,1) app.x(2,2) app.x(1,3)]==0);
                app.displayimage;
                if temp==3
                    app.x(1,temp)=-1;
                    fn = sprintf('Button_%d',3);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = 1;
                    app.Image.Layout.Column = temp;
                elseif temp==1
                    app.x(3,temp)=-1;
                    fn = sprintf('Button_%d',7);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = 3;
                    app.Image.Layout.Column = temp;
                else
                    app.x(temp,temp)=-1;
                    fn = sprintf('Button_%d',5);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = temp;
                    app.Image.Layout.Column = temp;
                end
                app.turn=app.turn+1;
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                app.GameLogic(0)
            end
            for i=1:3
                if sum(app.x(:,i))==2 && ~done
                    done=true;
                    temp=[];
                    temp=app.x(:,i);
                    app.x(find(temp==0),i)=-1;
                    fn = sprintf('Button_%d',i+3*(find(temp==0)-1));
                    app.(fn).Enable='off';
                    app.turn=app.turn+1;
                    app.displayimage;
                    app.Image.Layout.Row = find(temp==0);
                    app.Image.Layout.Column = i;
                    app.Image.ImageSource = 'Circle.jpg';
                    app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                    app.GameLogic(0)
                    break
                end
            end
            for i =1:3
                if sum(app.x(i,:))==2 && ~done
                    done=true;
                    temp=[];
                    temp=app.x(i,:);
                    app.x(i,find(temp==0))=-1;
                    fn = sprintf('Button_%d',find(temp==0)+3*(i-1));
                    app.(fn).Enable='off';
                    app.turn=app.turn+1;
                    app.displayimage;
                    app.Image.Layout.Row = i;
                    app.Image.Layout.Column = find(temp==0);
                    app.Image.ImageSource = 'Circle.jpg';
                    app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                    app.GameLogic(0)
                    break
                end
            end
            if sum(diag(app.x))==2 && ~done
                done=true;
                temp=[];
                temp=find(diag(app.x)==0);
                app.x(temp,temp)=-1;
                fn = sprintf('Button_%d',temp^2+1-rem(temp,2));
                app.(fn).Enable='off';
                app.turn=app.turn+1;
                app.displayimage;
                app.Image.Layout.Row = temp;
                app.Image.Layout.Column = temp;
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                app.GameLogic(0)
            end
            if sum([app.x(3,1) app.x(2,2) app.x(1,3)])==2 && ~done
                done=true;
                temp=[];
                temp=find([app.x(3,1) app.x(2,2) app.x(1,3)]==0);
                app.displayimage;
                if temp==3
                    app.x(1,temp)=-1;
                    fn = sprintf('Button_%d',3);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = 1;
                    app.Image.Layout.Column = temp;
                elseif temp==1
                    app.x(3,temp)=-1;
                    fn = sprintf('Button_%d',7);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = 3;
                    app.Image.Layout.Column = temp;
                else
                    app.x(temp,temp)=-1;
                    fn = sprintf('Button_%d',5);
                    app.(fn).Enable='off';
                    app.Image.Layout.Row = temp;
                    app.Image.Layout.Column = temp;
                end
                app.turn=app.turn+1;
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                app.GameLogic(0)
            end
            if app.x(2,2)==0 && ~done
                done=true;
                temp=[];
                app.x(2,2)=-1;
                fn = sprintf('Button_%d',5);
                app.(fn).Enable='off';
                app.turn=app.turn+1;
                app.displayimage;
                app.Image.Layout.Row = 2;
                app.Image.Layout.Column = 2;
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                app.GameLogic(0)
            end
            if app.turn==2 && app.x(2,2)==1
                done=true;
                temp=[];
                temp=[1 3 7 9];
                position=temp(ceil(rand(1)*length(temp)));
                k=[1 2 3;4 5 6;7 8 9];
                found=false;
                for m=1:3
                    for n=1:3
                        if k(m,n)==position
                            found=true;
                            app.x(m,n)=-1;
                            fn = sprintf('Button_%d',position);
                            app.(fn).Enable='off';
                            app.turn=app.turn+1;
                            app.displayimage;
                            app.Image.Layout.Row = m;
                            app.Image.Layout.Column = n;
                            app.Image.ImageSource = 'Circle.jpg';
                            app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                            app.GameLogic(0)
                            break
                        end
                    end
                    if found
                        break
                    end
                end
            end
            if app.turn==4 && ((app.x(3,1)==1 & app.x(1,3)==1) | (app.x(1,1)==1 & app.x(3,3)==1)) && ~done
                done=true;
                temp=[];
                temp=[2 4 6 8];
                position=temp(ceil(rand(1)*length(temp)));
                k=[1 2 3;4 5 6;7 8 9];
                found=false;
                for m=1:3
                    for n=1:3
                        if k(m,n)==position
                            found=true;
                            app.x(m,n)=-1;
                            fn = sprintf('Button_%d',position);
                            app.(fn).Enable='off';
                            app.turn=app.turn+1;
                            app.displayimage;
                            app.Image.Layout.Row = m;
                            app.Image.Layout.Column = n;
                            app.Image.ImageSource = 'Circle.jpg';
                            app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                            app.GameLogic(0)
                            break
                        end
                    end
                    if found
                        break
                    end
                end
            end
            if app.turn==4 && (app.x(1,1)==1 | app.x(1,3)==1 | app.x(3,1)==1 | app.x(3,3)==1) && ~done
                done=true;
                temp=[];
                temp=find([app.x(1,1) 5 app.x(1,3);5 5 5;app.x(3,1) 5 app.x(3,3)]==0);
                position=temp(ceil(rand(1)*length(temp)));               
                k=[1 2 3;4 5 6;7 8 9];
                found=false;
                for m=1:3
                    for n=1:3
                        if k(m,n)==position
                            found=true;
                            app.x(m,n)=-1;
                            fn = sprintf('Button_%d',position);
                            app.(fn).Enable='off';
                            app.turn=app.turn+1;
                            app.displayimage;
                            app.Image.Layout.Row = m;
                            app.Image.Layout.Column = n;
                            app.Image.ImageSource = 'Circle.jpg';
                            app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                            app.GameLogic(0)
                            break
                        end
                    end
                    if found
                        break
                    end
                end
            end
            if ~done
                temp=[];
                temp=find(app.x'==0);
                position=temp(ceil(rand(1)*length(temp)));
                k=[1 2 3;4 5 6;7 8 9];
                found=false;
                for m=1:3
                    for n=1:3
                        if k(m,n)==position
                            found=true;
                            app.x(m,n)=-1;
                            fn = sprintf('Button_%d',position);
                            app.(fn).Enable='off';
                            app.turn=app.turn+1;
                            app.displayimage;
                            app.Image.Layout.Row = m;
                            app.Image.Layout.Column = n;
                            app.Image.ImageSource = 'Circle.jpg';
                            app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
                            app.GameLogic(0)
                            break
                        end
                    end
                    if found
                        break
                    end
                end
            end
        end

        % Initialize to display an image
        function displayimage(app)
            app.Image = uiimage(app.GridLayout);
            app.Image.Interruptible = 'on';
            app.Image.Enable = 'on';
            app.Image.BackgroundColor = [1 1 1];
        end
         
        
        % Game Logic
        function GameLogic(app,pos)
            if app.turn==2
                app.PlaySinglePlayerButton.Enable ='off';
                app.PlaySinglePlayerButton.BackgroundColor = [1 0.4118 0.1608];
                app.PlaySinglePlayerButton.Text = {''};
            end
            switch pos  % check the position of the buttionbeing pressed and change board  
                case 1
                    if rem(app.turn,2)==0
                        app.x(1,1)=1;
                    else
                        app.x(1,1)=-1;
                    end
                case 2
                    if rem(app.turn,2)==0
                        app.x(1,2)=1;
                    else
                        app.x(1,2)=-1;
                    end
                case 3
                    if rem(app.turn,2)==0
                        app.x(1,3)=1;
                    else
                        app.x(1,3)=-1;
                    end
                case 4
                    if rem(app.turn,2)==0
                        app.x(2,1)=1;
                    else
                        app.x(2,1)=-1;
                    end
                case 5
                    if rem(app.turn,2)==0
                        app.x(2,2)=1;
                    else
                        app.x(2,2)=-1;
                    end
                case 6
                    if rem(app.turn,2)==0
                        app.x(2,3)=1;
                    else
                        app.x(2,3)=-1;
                    end
                case 7
                    if rem(app.turn,2)==0
                        app.x(3,1)=1;
                    else
                        app.x(3,1)=-1;
                    end
                case 8
                    if rem(app.turn,2)==0
                        app.x(3,2)=1;
                    else
                        app.x(3,2)=-1;
                    end
                case 9
                    if rem(app.turn,2)==0
                        app.x(3,3)=1;
                    else
                        app.x(3,3)=-1;
                    end
            end
            
            % Check if someone has won 
            for i=1:3
                if sum(app.x(:,i))==3
                    GameOver(app,'X');
                    break
                elseif sum(app.x(:,i))==-3
                    GameOver(app,'O');
                    break
                elseif sum(app.x(i,:))==3
                    GameOver(app,'X');
                    break
                elseif sum(app.x(i,:))==-3
                    GameOver(app,'O');
                    break
                elseif sum(diag(app.x))==3
                    GameOver(app,'X');
                    break
                elseif sum(diag(app.x))==-3
                    GameOver(app,'O');
                    break
                elseif sum([app.x(3,1) app.x(2,2) app.x(1,3)])==3
                    GameOver(app,'X');
                    break
                elseif sum([app.x(3,1) app.x(2,2) app.x(1,3)])==-3
                    GameOver(app,'O');
                    break
                elseif app.turn==10
                    GameOver(app,'');
                end
            end
        end
        
        
        % Show results and end the game
        function GameOver(app,str)
            if str=='X'
                app.TextArea_Win.BackgroundColor = [0 1 0];
                app.TextArea_Win.Value = {''; 'X Wins'};
                app.TextArea_PlayerTurn.Value = {''};
                app.TextArea_PlayerTurn.Enable = 'off';
                disablebutton(app)
                app.turn=1;
                pause(2);
                TicTacToe();
                app.gameover=true;
                return
            elseif str=='O'
                app.TextArea_Win.BackgroundColor = [0 1 0];
                app.TextArea_Win.Value = {''; 'O Wins'};
                app.TextArea_PlayerTurn.Value = {''};
                app.TextArea_PlayerTurn.Enable = 'off';
                disablebutton(app)
                app.turn=1;
                pause(2);
                TicTacToe();
                app.gameover=true;
                return
            elseif app.turn==10
                app.TextArea_Win.BackgroundColor = [1 0 0];
                app.TextArea_Win.Value = {''; 'Game Over'};
                app.TextArea_PlayerTurn.Value = {''};
                app.TextArea_PlayerTurn.Enable = 'off';
                disablebutton(app)
                app.turn=1;
                pause(2);
                TicTacToe();
                app.gameover=true;
                return
            end
        end
        
        % Disable all buttion after game is over
        function disablebutton(app)
            app.Button_1.Enable = 'off';
            app.Button_2.Enable = 'off';
            app.Button_3.Enable = 'off';
            app.Button_4.Enable = 'off';
            app.Button_5.Enable = 'off';
            app.Button_6.Enable = 'off';
            app.Button_7.Enable = 'off';
            app.Button_8.Enable = 'off';
            app.Button_9.Enable = 'off';

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Button_1.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 1;
            app.Image.Layout.Column = 1;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,1);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 1;
            app.Image.Layout.Column = 2;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,2);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 1;
            app.Image.Layout.Column = 3;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,3);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 2;
            app.Image.Layout.Column =1;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,4);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 2;
            app.Image.Layout.Column = 2;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,5);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 2;
            app.Image.Layout.Column = 3;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,6);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Button_7.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 3;
            app.Image.Layout.Column = 1;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,7);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Button_8.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 3;
            app.Image.Layout.Column = 2;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,8);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Button_9.Enable = 'off';
            app.displayimage;
            app.Image.Layout.Row = 3;
            app.Image.Layout.Column = 3;
            if rem(app.turn,2)==1
                app.Image.ImageSource = 'Cross.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'O''s Turn'};
            else
                app.Image.ImageSource = 'Circle.jpg';
                app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            end
            app.turn=app.turn+1;
            GameLogic(app,9);
            if app.singleplayer && ~app.gameover
                app.AI;
            end
        end

        % Button pushed function: PlaySinglePlayerButton
        function PlaySinglePlayerButtonPushed(app, event)
            app.singleplayer=true;
            app.PlaySinglePlayerButton.Enable ='off';
            app.PlaySinglePlayerButton.BackgroundColor = [1 0.4118 0.1608];
            app.PlaySinglePlayerButton.Text = {''};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea_PlayerTurn = uitextarea(app.GridLayout);
            app.TextArea_PlayerTurn.Editable = 'off';
            app.TextArea_PlayerTurn.Value = {''; 'X''s Turn'};
            app.PlaySinglePlayerButton = uibutton(app.GridLayout, 'push');
            app.PlaySinglePlayerButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySinglePlayerButtonPushed, true);
            app.PlaySinglePlayerButton.Text = {'Play '; 'SinglePlayer'};
            app.TextArea_Win = uitextarea(app.GridLayout);
            app.TextArea_Win.Value = {''; ''};
            app.TextArea_2Label = uilabel(app.GridLayout);
            app.TextArea_2Label.Visible = 'off';
            app.TextArea_2Label.Text = 'Text Area';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end